classdef Automatas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ControlPuenteGruaUIFigure      matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        ConfiguracionTab               matlab.ui.container.Tab
        CONFIGURACINLabel              matlab.ui.control.Label
        StartButton                    matlab.ui.control.Button
        StopButton                     matlab.ui.control.Button
        ALTURACONTROLMANUALLabel       matlab.ui.control.Label
        AlturaManualMuelleEditFieldLabel  matlab.ui.control.Label
        AlturaManualMuelleEditField    matlab.ui.control.NumericEditField
        AlturaManualBarcoEditFieldLabel  matlab.ui.control.Label
        AlturaManualBarcoEditField     matlab.ui.control.NumericEditField
        POSICININICIALLabel            matlab.ui.control.Label
        PosicinObjetivoIncrementalCheckBox  matlab.ui.control.CheckBox
        PosicinObjetivoEditFieldLabel  matlab.ui.control.Label
        PosicinObjetivoEditField       matlab.ui.control.NumericEditField
        LMITESCARROLabel               matlab.ui.control.Label
        LmiteIzquierdoEditFieldLabel   matlab.ui.control.Label
        LmiteIzquierdoEditField        matlab.ui.control.NumericEditField
        LmiteDerechoEditFieldLabel     matlab.ui.control.Label
        LmiteDerechoEditField          matlab.ui.control.NumericEditField
        LMITESIZAJELabel               matlab.ui.control.Label
        LmiteInferiorBarcoEditFieldLabel  matlab.ui.control.Label
        LmiteInferiorBarcoEditField    matlab.ui.control.NumericEditField
        AlturaMuelleEditFieldLabel     matlab.ui.control.Label
        AlturaMuelleEditField          matlab.ui.control.NumericEditField
        AlturaCarroEditFieldLabel      matlab.ui.control.Label
        AlturaCarroEditField           matlab.ui.control.NumericEditField
        AlturaVigaTesteraEditFieldLabel  matlab.ui.control.Label
        AlturaVigaTesteraEditField     matlab.ui.control.NumericEditField
        EscenarioContenedorTab         matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        OcupacionEditFieldLabel        matlab.ui.control.Label
        OcupacionEditField             matlab.ui.control.NumericEditField
        GenerarButton                  matlab.ui.control.Button
        AceptarButton                  matlab.ui.control.Button
        XContenedorMuelleEditFieldLabel  matlab.ui.control.Label
        XContenedorMuelleEditField     matlab.ui.control.NumericEditField
        XDescargaMuelleEditFieldLabel  matlab.ui.control.Label
        XDescargaMuelleEditField       matlab.ui.control.NumericEditField
        HDMITab                        matlab.ui.container.Tab
        HDMILabel                      matlab.ui.control.Label
        PulsadordeEmergenciaSwitchLabel  matlab.ui.control.Label
        PulsadordeEmergenciaSwitch     matlab.ui.control.Switch
        FCU_DSwitchLabel               matlab.ui.control.Label
        FCU_DSwitch                    matlab.ui.control.Switch
        FCU_ISwitchLabel               matlab.ui.control.Label
        FCU_ISwitch                    matlab.ui.control.Switch
        ModoSwitchLabel                matlab.ui.control.Label
        ModoSwitch                     matlab.ui.control.RockerSwitch
        TomarButton                    matlab.ui.control.Button
        LiberarButton                  matlab.ui.control.Button
        XActualEditFieldLabel          matlab.ui.control.Label
        XActualEditField               matlab.ui.control.EditField
        YActualEditFieldLabel          matlab.ui.control.Label
        YActualEditField               matlab.ui.control.EditField
        CarroSliderLabel               matlab.ui.control.Label
        CarroSlider                    matlab.ui.control.Slider
        IzajeSliderLabel               matlab.ui.control.Label
        IzajeSlider                    matlab.ui.control.Slider
        ColumnaIncrementalCheckBox     matlab.ui.control.CheckBox
        ColumnaObjetivoDropDownLabel   matlab.ui.control.Label
        ColumnaObjetivoDropDown        matlab.ui.control.DropDown
        Label                          matlab.ui.control.Label
        Lamp                           matlab.ui.control.Lamp
        ControlAnguloSwitchLabel       matlab.ui.control.Label
        ControlAnguloSwitch            matlab.ui.control.Switch
        IncrementalSwitch              matlab.ui.control.ToggleSwitch
        CicloButtonGroup               matlab.ui.container.ButtonGroup
        SimpleCargaButton              matlab.ui.control.RadioButton
        SimpleDescargaButton           matlab.ui.control.RadioButton
        DobleButton                    matlab.ui.control.RadioButton
        GrficasTab                     matlab.ui.container.Tab
        TabGroup2                      matlab.ui.container.TabGroup
        EscenarioTab                   matlab.ui.container.Tab
        PlotEscenario                  matlab.ui.control.UIAxes
        CarroSlider_2Label             matlab.ui.control.Label
        CarroSlider_2                  matlab.ui.control.Slider
        IzajeSlider_2Label             matlab.ui.control.Label
        IzajeSlider_2                  matlab.ui.control.Slider
        PosicionesTab                  matlab.ui.container.Tab
        PlotPosicionX                  matlab.ui.control.UIAxes
        PlotPosicionY                  matlab.ui.control.UIAxes
        VelocidadesTab                 matlab.ui.container.Tab
        PlotVelocidadX                 matlab.ui.control.UIAxes
        PlotVelocidadY                 matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        x_actual; % Description
        y_actual; % Description
        Cargado; % Description
        envolvente_total;
        barco_contenedores;
        cont_muelle_masa;
        envolvente_in;
        xt;
        tiempo;
        xt_vector;
    end
    
    methods (Access = public)
        
        function actualizarPosiciones(app, in1,in2,in3,in4,in5,in6)
            % Create an object that gets the run-time value of the specified block
            % Update the GUI accordingly. In this case a the value of an EditField is being updated.
            app.x_actual=[app.x_actual,in1];
            app.y_actual=[app.y_actual,in2];
            app.tiempo=[app.tiempo,in4];
            app.xt=in5;
            app.xt_vector=[app.xt_vector,in5];
            app.envolvente_in=in6;
            if in3==1
                %                 app.EditField.Value="Cargado";
                app.Cargado="Cargado";
            elseif in3==-1
                %                 app.EditField.Value="Descargado";
                app.Cargado="Descargado";
            end
            app.XActualEditField.Value = num2str(app.x_actual(length(app.x_actual)));
            app.YActualEditField.Value = num2str(app.y_actual(length(app.y_actual)));
            %             hold(app.PlotPosicionX,'off');
            plot(app.PlotPosicionX,app.tiempo,app.x_actual);
            %             plot(app.PlotPosicionX,in4,in1);
            %             hold(app.PlotPosicionY,'off');
            plot(app.PlotPosicionY,app.tiempo,app.y_actual);
            %             plot(app.PlotPosicionY,in4,in2);
            % Grafico del Escenario --> se movera a una funcion aparte
            hold(app.PlotEscenario,'off');
            base_x=[-38 0 0];
            base_y=[0 0 -30];
            barco_x=[5 5 50 50];
            barco_y=[15 -20 -20 15];
            mar_x1=[0 5];
            mar_y1=[-5 -5];
            mar_x2=[50 68];
            mar_y2=[-5 -5];
            carro_x=[-38 68];
            carro_y=[45 45];
            ind_muelle=((30+0)/0.5)+1;
            ind_barco=((30+5)/0.5)+1;
            stairs(app.PlotEscenario,app.envolvente_in(1,(1:ind_muelle)),app.envolvente_in(2,(1:ind_muelle)),'k-')
            hold(app.PlotEscenario)
            for i=ind_barco:5:length(app.envolvente_in(1,:))-1
                cant_col=(app.envolvente_in(2,i)-(-20))/2.5;
                if cant_col~=0
                    for j=1:cant_col
                        [cont_x,cont_y]=contenedor(app.envolvente_in(1,i)+1.25,-20+2.5*(j-1)+1.25,2.5);
                        plot(app.PlotEscenario,cont_x,cont_y,'m-')
                        %                         hold(app.PlotEscenario)
                    end
                end
            end
            %             hold(app.PlotEscenario);
            %             hold(app.PlotEscenario);
            plot(app.PlotEscenario,base_x,base_y,'k-',barco_x,barco_y,'g-',mar_x1,mar_y1,'b-',mar_x2,mar_y2,'b-',carro_x,carro_y,'k-');
            plot(app.PlotEscenario,[app.xt app.x_actual(length(app.x_actual))],[45 app.y_actual(length(app.y_actual))],'k-');
            plot(app.PlotEscenario,[(app.xt-2) (app.xt-2) (app.xt+2) (app.xt+2)],[45 46.25 46.25 45],'k-');
%             app.Cargado="Cargado";
            if app.Cargado=="Cargado"
                tita=atan2(app.x_actual(length(app.x_actual))-app.xt,45-app.y_actual(length(app.y_actual)));
                rot_matrix=[cos(0) -sin(0);sin(0) cos(0)];
                vector_1=rot_matrix*[app.x_actual(length(app.x_actual))-1.25;app.y_actual(length(app.y_actual))-2.5];
                vector_2=rot_matrix*[app.x_actual(length(app.x_actual))-1.25;app.y_actual(length(app.y_actual))];
                vector_3=rot_matrix*[app.x_actual(length(app.x_actual))+1.25;app.y_actual(length(app.y_actual))];
                vector_4=rot_matrix*[app.x_actual(length(app.x_actual))+1.25;app.y_actual(length(app.y_actual))-2.5];
                plot(app.PlotEscenario,[vector_1(1) vector_2(1) vector_3(1) vector_4(1) vector_1(1)],[vector_1(2) vector_2(2) vector_3(2) vector_4(2) vector_1(2)],'m-');
            end
            app.PlotEscenario.YLim = [-30 50];
            app.PlotEscenario.XLim=[-30 60];


        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            set_param('Automata' ,'SimulationCommand','pause');
            assignin('base','x_izq',app.LmiteIzquierdoEditField.Value);
            assignin('base','x_der',app.LmiteDerechoEditField.Value);
            assignin('base','PObjetivo_incremental',app.PosicinObjetivoIncrementalCheckBox.Value);
            assignin('base','PObjetivo_fijo',app.PosicinObjetivoEditField.Value);
            assignin('base','Y_inf_barco',app.LmiteInferiorBarcoEditField.Value);
            assignin('base','Y_muelle',app.AlturaMuelleEditField.Value);
            assignin('base','Y_t0',app.AlturaCarroEditField.Value);
            assignin('base','Y_sb',app.AlturaVigaTesteraEditField.Value);
            assignin('base','AlturaInicialMuelle',app.AlturaManualMuelleEditField.Value);
            assignin('base','AlturaInicialBarco',app.AlturaManualBarcoEditField.Value);
            assignin('base','Y_max',40);
            assignin('base','cont_muelle_masa',0);
            evalin('base','Variables');
            set_param('Automata','SimulationCommand','update');
            set_param('Automata','SimulationCommand','continue');
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            set_param('Automata','SimulationCommand','stop');
            assignin('base','grafica_xl',app.x_actual);
            assignin('base','grafica_yl',app.y_actual);
            assignin("base",'grafica_xt',app.xt_vector)
            assignin('base','grafica_envolvente',app.envolvente_in);
        end

        % Button pushed function: GenerarButton
        function GenerarButtonPushed(app, event)
            hold(app.UIAxes,'off');
            l_cont=2.5;
            lim_xi=-30+l_cont/2;
            lim_xd=0-l_cont/2;
            ml_min=2000;
            ml_max=50000;
            base_x=[-38 0 0];
            base_y=[0 0 -30];
            barco_x=[5 5 50 50];
            barco_y=[15 -20 -20 15];
            mar_x1=[0 5];
            mar_y1=[-5 -5];
            mar_x2=[50 68];
            mar_y2=[-5 -5];
            carro_x=[-38 68];
            carro_y=[45 45];
            capacidad_lateral=((barco_x(1,3)-barco_x(1,2))/l_cont);
            capacidad_vertical=((25-barco_y(1,2))/l_cont);
            %% Escenario aleatorio de contenedores en muelle y barco
            capacidad_Total=capacidad_lateral*capacidad_vertical;
            ocupacion=floor(app.OcupacionEditField.Value*capacidad_Total);
            %Creacion de contenedor en muelle
            cont_muelle_x=app.XContenedorMuelleEditField.Value+l_cont/2;
            [cont_x,cont_y]=contenedor(cont_muelle_x,1.25,l_cont);
            cont_muelle_x=(30+(cont_muelle_x-1.25))/0.5;
            cont_muelle_y=l_cont;
            app.cont_muelle_masa=ml_min+(ml_max-ml_min)*rand();
            plot(app.UIAxes,cont_x,cont_y,'k-');
            hold(app.UIAxes);
            location=zeros(3,ocupacion);
            i=1;
            while (i<=ocupacion)
                cont_x=randi([1 capacidad_lateral]);
                cont_y=randi([1 capacidad_vertical]);
                cont_masa=ml_min+(ml_max-ml_min)*rand();
                if cont_y==1
                    ind_y=0;
                else
                    ind_y=cont_y-1;
                end
                if(ind_y==0 && CheckContenedor(cont_x,cont_y,location(1:2,:))==0)
                    location(:,i)=[cont_x;cont_y;cont_masa];
                    [cont_x,cont_y]=contenedor(5+(cont_x-1)*l_cont+l_cont/2,-20+cont_y*l_cont/2,l_cont);
                    plot(app.UIAxes,cont_x,cont_y,'m-')
                    %                     hold(app.UIAxes)
                    i=i+1;
                elseif(ind_y~=0 && CheckContenedor(cont_x,cont_y,location(1:2,:))==0 && CheckContenedor(cont_x,ind_y,location(1:2,:))==1)
                    location(:,i)=[cont_x;cont_y;cont_masa];
                    [cont_x,cont_y]=contenedor(5+(cont_x-1)*l_cont+l_cont/2,-20+(cont_y-1)*l_cont+l_cont/2,l_cont);
                    plot(app.UIAxes,cont_x,cont_y,'m-')
                    %                     hold(app.UIAxes)
                    i=i+1;
                else
                end
                
            end
            %% Graficar
            plot(app.UIAxes,base_x,base_y,'k-',barco_x,barco_y,'g-',mar_x1,mar_y1,'b-',mar_x2,mar_y2,'b-',carro_x,carro_y,'k-');
            app.UIAxes.YLim = [-30 50];
            app.UIAxes.XLim=[-30 60];
            [temp, order] = sort(location(1,:));
            contenedores_ordenados = location(:,order);
            app.barco_contenedores=zeros(18,18);
            length(contenedores_ordenados)
            for i=1:length(contenedores_ordenados)
                app.barco_contenedores(contenedores_ordenados(1,i),contenedores_ordenados(2,i))=contenedores_ordenados(3,i);
            end
            app.barco_contenedores=app.barco_contenedores';
            barcos_envolvente=zeros(1,18);
            for j=1:length(app.barco_contenedores)
                temp=find(app.barco_contenedores(:,j)==0);
                if(temp~=0)
                    barcos_envolvente(1,j)=temp(1)-1;
                else
                    barcos_envolvente(1,j)=18;
                end
            end
            envolvente=zeros(1,90);
            for i=1:length(barcos_envolvente)
                for j=1:6
                    envolvente(1,(i-1)*5+j)=barcos_envolvente(i).*2.5-20;
                end
            end
            x=linspace(-30,50,161);
            y=zeros(1,161);
            for i=1:67
                y(i)=0;
            end
            for i=68:70
                y(i)=15;
            end
            
            for i=71:length(y)
                y(i)=envolvente(i-70);
            end
            for i=1:5
                y(cont_muelle_x+i)=cont_muelle_y;
            end
            stairs(app.UIAxes,x,y,'r')
            
            app.envolvente_total(1,:)=x;
            app.envolvente_total(2,:)=y;
            txt = {'Posicion Contenedor X: ' num2str(x(cont_muelle_x+1)+1.25) '','Posicion Contenedor Y: 2.5'};
            text(app.UIAxes,-30,35,txt)
            
            
        end

        % Button pushed function: AceptarButton
        function AceptarButtonPushed(app, event)
            %             set_param('Automata' ,'SimulationCommand','pause');
            assignin('base','envolvente',app.envolvente_total);
            assignin('base','contenedores',app.barco_contenedores);
            assignin('base','CargaDescarga',0);
            assignin('base','cont_muelle_masa',app.cont_muelle_masa);
            %             set_param('Automata','SimulationCommand','update');
            %             set_param('Automata','SimulationCommand','continue');
        end

        % Value changed function: PulsadordeEmergenciaSwitch
        function PulsadordeEmergenciaSwitchValueChanged(app, event)
            value=app.PulsadordeEmergenciaSwitch.Value;
            if value=="On"
                set_param('Automata/PulsEmerg' ,'Value','1');
                app.Lamp.Color='red';
            else
                set_param('Automata/PulsEmerg' ,'Value','0');
                app.Lamp.Color='green';
                
            end
            
            
        end

        % Value changed function: ModoSwitch
        function ModoSwitchValueChanged(app, event)
            value = app.ModoSwitch.Value;
            if value=="Manual"
                set_param('Automata/Modo' ,'Value','1');
            else
                set_param('Automata/Modo' ,'Value','-1');
            end
        end

        % Value changed function: FCU_DSwitch
        function FCU_DSwitchValueChanged(app, event)
            value = app.FCU_DSwitch.Value;
            if value=="On"
                set_param('Automata/FinCarreraUltimoDer' ,'Value','1');
                app.Lamp.Color='red';
            else
                set_param('Automata/FinCarreraUltimoDer' ,'Value','0');
                app.Lamp.Color='green';
            end
        end

        % Value changed function: FCU_ISwitch
        function FCU_ISwitchValueChanged(app, event)
            value = app.FCU_ISwitch.Value;
            if value=="On"
                set_param('Automata/FinCarreraUltimoIzq' ,'Value','1');
                app.Lamp.Color='red';
            else
                set_param('Automata/FinCarreraUltimoIzq' ,'Value','0');
                app.Lamp.Color='green';
            end
            
        end

        % Button pushed function: TomarButton
        function TomarButtonPushed(app, event)
            set_param('Automata/TomarLiberar' ,'Value','1');
            pause(0.1)
            set_param('Automata/TomarLiberar' ,'Value','0');
        end

        % Button pushed function: LiberarButton
        function LiberarButtonPushed(app, event)
            set_param('Automata/TomarLiberar' ,'Value','-1');
            pause(0.1)
            set_param('Automata/TomarLiberar' ,'Value','0');
        end

        % Value changing function: CarroSlider, CarroSlider_2
        function CarroSliderValueChanging(app, event)
            changingValue = event.Value;
            set_param('Automata/CarroManual' ,'Value',num2str(changingValue));
        end

        % Value changing function: IzajeSlider, IzajeSlider_2
        function IzajeSliderValueChanging(app, event)
            changingValue = event.Value;
            set_param('Automata/IzajeManual' ,'Value',num2str(changingValue));
        end

        % Value changed function: ColumnaObjetivoDropDown
        function ColumnaObjetivoDropDownValueChanged(app, event)
            value = app.ColumnaObjetivoDropDown.Value;
            set_param('Automata/ColumnaObjetivo' ,'Value',num2str(value));
        end

        % Value changed function: ControlAnguloSwitch
        function ControlAnguloSwitchValueChanged(app, event)
            value = app.ControlAnguloSwitch.Value;
            if value=="On"
                set_param('Automata/ControlAngulo' ,'Value','1');
            else
                set_param('Automata/ControlAngulo' ,'Value','-1');
            end
        end

        % Value changed function: ColumnaIncrementalCheckBox
        function ColumnaIncrementalCheckBoxValueChanged(app, event)
            value = app.ColumnaIncrementalCheckBox.Value;
            set_param('Automata/ColumnaIncremental','Value',num2str(value));
        end

        % Selection changed function: CicloButtonGroup
        function CicloButtonGroupSelectionChanged(app, event)
            selectedButton = app.CicloButtonGroup.SelectedObject;
            if selectedButton == app.SimpleCargaButton
                valor=1;
            elseif selectedButton == app.SimpleDescargaButton
                valor=2;
            elseif selectedButton == app.DobleButton
                valor=3;
            end
            set_param('Automata/CicloOperacion','Value',num2str(valor));
        end

        % Value changed function: IncrementalSwitch
        function IncrementalSwitchValueChanged(app, event)
            value = app.IncrementalSwitch.Value;
            if value=='+'
                set_param('Automata/IncDec','Value','1');
            elseif value=='-'
                set_param('Automata/IncDec','Value','-1');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ControlPuenteGruaUIFigure = uifigure('Visible', 'off');
            app.ControlPuenteGruaUIFigure.Name = 'Control Puente Grua';
            app.TabGroup = uitabgroup(app.ControlPuenteGruaUIFigure);
            app.ConfiguracionTab = uitab(app.TabGroup);
            app.ConfiguracionTab.Title = 'Configuracion';
            app.CONFIGURACINLabel = uilabel(app.ConfiguracionTab);
            app.CONFIGURACINLabel.Text = 'CONFIGURACIÓN';
            app.StartButton = uibutton(app.ConfiguracionTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.ConfiguracionTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ALTURACONTROLMANUALLabel = uilabel(app.ConfiguracionTab);
            app.ALTURACONTROLMANUALLabel.Text = 'ALTURA CONTROL MANUAL';
            app.AlturaManualMuelleEditFieldLabel = uilabel(app.ConfiguracionTab);
            app.AlturaManualMuelleEditFieldLabel.Text = 'Altura Manual Muelle';
            app.AlturaManualMuelleEditField = uieditfield(app.ConfiguracionTab, 'numeric');
            app.AlturaManualMuelleEditField.Value = 2.5;
            app.AlturaManualBarcoEditFieldLabel = uilabel(app.ConfiguracionTab);
            app.AlturaManualBarcoEditFieldLabel.Text = 'Altura Manual Barco';
            app.AlturaManualBarcoEditField = uieditfield(app.ConfiguracionTab, 'numeric');
            app.AlturaManualBarcoEditField.Value = 2.5;
            app.POSICININICIALLabel = uilabel(app.ConfiguracionTab);
            app.POSICININICIALLabel.Text = 'POSICIÓN INICIAL';
            app.PosicinObjetivoIncrementalCheckBox = uicheckbox(app.ConfiguracionTab);
            app.PosicinObjetivoIncrementalCheckBox.Text = 'Posición Objetivo Incremental';
            app.PosicinObjetivoEditFieldLabel = uilabel(app.ConfiguracionTab);
            app.PosicinObjetivoEditFieldLabel.Text = 'Posición Objetivo';
            app.PosicinObjetivoEditField = uieditfield(app.ConfiguracionTab, 'numeric');
            app.PosicinObjetivoEditField.Value = -30;
            app.LMITESCARROLabel = uilabel(app.ConfiguracionTab);
            app.LMITESCARROLabel.Text = 'LÍMITES CARRO';
            app.LmiteIzquierdoEditFieldLabel = uilabel(app.ConfiguracionTab);
            app.LmiteIzquierdoEditFieldLabel.Text = 'Límite Izquierdo ';
            app.LmiteIzquierdoEditField = uieditfield(app.ConfiguracionTab, 'numeric');
            app.LmiteIzquierdoEditField.Value = -30;
            app.LmiteDerechoEditFieldLabel = uilabel(app.ConfiguracionTab);
            app.LmiteDerechoEditFieldLabel.Text = 'Límite Derecho';
            app.LmiteDerechoEditField = uieditfield(app.ConfiguracionTab, 'numeric');
            app.LmiteDerechoEditField.Value = 50;
            app.LMITESIZAJELabel = uilabel(app.ConfiguracionTab);
            app.LMITESIZAJELabel.Text = 'LÍMITES IZAJE';
            app.LmiteInferiorBarcoEditFieldLabel = uilabel(app.ConfiguracionTab);
            app.LmiteInferiorBarcoEditFieldLabel.Text = 'Límite Inferior Barco';
            app.LmiteInferiorBarcoEditField = uieditfield(app.ConfiguracionTab, 'numeric');
            app.LmiteInferiorBarcoEditField.Value = -20;
            app.AlturaMuelleEditFieldLabel = uilabel(app.ConfiguracionTab);
            app.AlturaMuelleEditFieldLabel.Text = 'Altura Muelle';
            app.AlturaMuelleEditField = uieditfield(app.ConfiguracionTab, 'numeric');
            app.AlturaCarroEditFieldLabel = uilabel(app.ConfiguracionTab);
            app.AlturaCarroEditFieldLabel.Text = 'Altura Carro';
            app.AlturaCarroEditField = uieditfield(app.ConfiguracionTab, 'numeric');
            app.AlturaCarroEditField.Value = 45;
            app.AlturaVigaTesteraEditFieldLabel = uilabel(app.ConfiguracionTab);
            app.AlturaVigaTesteraEditFieldLabel.Text = 'Altura Viga Testera';
            app.AlturaVigaTesteraEditField = uieditfield(app.ConfiguracionTab, 'numeric');
            app.AlturaVigaTesteraEditField.Value = 15;
            app.EscenarioContenedorTab = uitab(app.TabGroup);
            app.EscenarioContenedorTab.Title = 'Escenario Contenedor';
            app.UIAxes = uiaxes(app.EscenarioContenedorTab);
            title(app.UIAxes, 'Escenario Contenedores')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.OcupacionEditFieldLabel = uilabel(app.EscenarioContenedorTab);
            app.OcupacionEditFieldLabel.Text = 'Ocupacion';
            app.OcupacionEditField = uieditfield(app.EscenarioContenedorTab, 'numeric');
            app.GenerarButton = uibutton(app.EscenarioContenedorTab, 'push');
            app.GenerarButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarButtonPushed, true);
            app.GenerarButton.Text = 'Generar';
            app.AceptarButton = uibutton(app.EscenarioContenedorTab, 'push');
            app.AceptarButton.ButtonPushedFcn = createCallbackFcn(app, @AceptarButtonPushed, true);
            app.AceptarButton.Text = 'Aceptar';
            app.XContenedorMuelleEditFieldLabel = uilabel(app.EscenarioContenedorTab);
            app.XContenedorMuelleEditFieldLabel.Text = 'X Contenedor Muelle';
            app.XContenedorMuelleEditField = uieditfield(app.EscenarioContenedorTab, 'numeric');
            app.XContenedorMuelleEditField.Limits = [-30 -2.5];
            app.XContenedorMuelleEditField.Value = -20;
            app.XDescargaMuelleEditFieldLabel = uilabel(app.EscenarioContenedorTab);
            app.XDescargaMuelleEditFieldLabel.Text = 'X Descarga Muelle';
            app.XDescargaMuelleEditField = uieditfield(app.EscenarioContenedorTab, 'numeric');
            app.XDescargaMuelleEditField.Limits = [-30 -2.5];
            app.XDescargaMuelleEditField.Value = -15;
            app.HDMITab = uitab(app.TabGroup);
            app.HDMITab.Title = 'HDMI';
            app.HDMILabel = uilabel(app.HDMITab);
            app.HDMILabel.Text = 'HDMI';
            app.PulsadordeEmergenciaSwitchLabel = uilabel(app.HDMITab);
            app.PulsadordeEmergenciaSwitchLabel.Text = 'Pulsador de Emergencia';
            app.PulsadordeEmergenciaSwitch = uiswitch(app.HDMITab, 'slider');
            app.PulsadordeEmergenciaSwitch.ValueChangedFcn = createCallbackFcn(app, @PulsadordeEmergenciaSwitchValueChanged, true);
            app.FCU_DSwitchLabel = uilabel(app.HDMITab);
            app.FCU_DSwitchLabel.Text = 'FCU_D';
            app.FCU_DSwitch = uiswitch(app.HDMITab, 'slider');
            app.FCU_DSwitch.ValueChangedFcn = createCallbackFcn(app, @FCU_DSwitchValueChanged, true);
            app.FCU_ISwitchLabel = uilabel(app.HDMITab);
            app.FCU_ISwitchLabel.Text = 'FCU_I';
            app.FCU_ISwitch = uiswitch(app.HDMITab, 'slider');
            app.FCU_ISwitch.ValueChangedFcn = createCallbackFcn(app, @FCU_ISwitchValueChanged, true);
            app.ModoSwitchLabel = uilabel(app.HDMITab);
            app.ModoSwitchLabel.Text = 'Modo';
            app.ModoSwitch = uiswitch(app.HDMITab, 'rocker');
            app.ModoSwitch.Items = {'Automatico', 'Manual'};
            app.ModoSwitch.ValueChangedFcn = createCallbackFcn(app, @ModoSwitchValueChanged, true);
            app.ModoSwitch.Value = 'Manual';
            app.TomarButton = uibutton(app.HDMITab, 'push');
            app.TomarButton.ButtonPushedFcn = createCallbackFcn(app, @TomarButtonPushed, true);
            app.TomarButton.Text = 'Tomar';
            app.LiberarButton = uibutton(app.HDMITab, 'push');
            app.LiberarButton.ButtonPushedFcn = createCallbackFcn(app, @LiberarButtonPushed, true);
            app.LiberarButton.Text = 'Liberar';
            app.XActualEditFieldLabel = uilabel(app.HDMITab);
            app.XActualEditFieldLabel.Text = 'X Actual';
            app.XActualEditField = uieditfield(app.HDMITab, 'text');
            app.YActualEditFieldLabel = uilabel(app.HDMITab);
            app.YActualEditFieldLabel.Text = 'Y Actual';
            app.YActualEditField = uieditfield(app.HDMITab, 'text');
            app.CarroSliderLabel = uilabel(app.HDMITab);
            app.CarroSliderLabel.Text = 'Carro';
            app.CarroSlider = uislider(app.HDMITab);
            app.CarroSlider.Limits = [-0.15 0.15];
            app.CarroSlider.MajorTicks = [-0.15 -0.1 -0.05 0 0.05 0.1 0.15];
            app.CarroSlider.ValueChangingFcn = createCallbackFcn(app, @CarroSliderValueChanging, true);
            app.IzajeSliderLabel = uilabel(app.HDMITab);
            app.IzajeSliderLabel.Text = 'Izaje';
            app.IzajeSlider = uislider(app.HDMITab);
            app.IzajeSlider.Limits = [-0.15 0.15];
            app.IzajeSlider.ValueChangingFcn = createCallbackFcn(app, @IzajeSliderValueChanging, true);
            app.ColumnaIncrementalCheckBox = uicheckbox(app.HDMITab);
            app.ColumnaIncrementalCheckBox.ValueChangedFcn = createCallbackFcn(app, @ColumnaIncrementalCheckBoxValueChanged, true);
            app.ColumnaIncrementalCheckBox.Text = 'Columna Incremental';
            app.ColumnaObjetivoDropDownLabel = uilabel(app.HDMITab);
            app.ColumnaObjetivoDropDownLabel.Text = 'Columna Objetivo';
            app.ColumnaObjetivoDropDown = uidropdown(app.HDMITab);
            app.ColumnaObjetivoDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18'};
            app.ColumnaObjetivoDropDown.ValueChangedFcn = createCallbackFcn(app, @ColumnaObjetivoDropDownValueChanged, true);
            app.ColumnaObjetivoDropDown.Value = '1';
            app.Label = uilabel(app.HDMITab);
            app.Label.Text = '';
            app.Lamp = uilamp(app.HDMITab);
            app.ControlAnguloSwitchLabel = uilabel(app.HDMITab);
            app.ControlAnguloSwitchLabel.Text = 'Control Angulo';
            app.ControlAnguloSwitch = uiswitch(app.HDMITab, 'slider');
            app.ControlAnguloSwitch.ValueChangedFcn = createCallbackFcn(app, @ControlAnguloSwitchValueChanged, true);
            app.ControlAnguloSwitch.Value = 'On';
            app.IncrementalSwitch = uiswitch(app.HDMITab, 'toggle');
            app.IncrementalSwitch.Items = {'+', '-'};
            app.IncrementalSwitch.ValueChangedFcn = createCallbackFcn(app, @IncrementalSwitchValueChanged, true);
            app.IncrementalSwitch.Value = '+';
            app.CicloButtonGroup = uibuttongroup(app.HDMITab);
            app.CicloButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CicloButtonGroupSelectionChanged, true);
            app.CicloButtonGroup.Title = 'Ciclo';
            app.SimpleCargaButton = uiradiobutton(app.CicloButtonGroup);
            app.SimpleCargaButton.Text = 'Simple Carga';
            app.SimpleCargaButton.Value = true;
            app.SimpleDescargaButton = uiradiobutton(app.CicloButtonGroup);
            app.SimpleDescargaButton.Text = 'Simple Descarga';
            app.DobleButton = uiradiobutton(app.CicloButtonGroup);
            app.DobleButton.Text = 'Doble';
            app.GrficasTab = uitab(app.TabGroup);
            app.GrficasTab.Title = 'Gráficas';
            app.TabGroup2 = uitabgroup(app.GrficasTab);
            app.EscenarioTab = uitab(app.TabGroup2);
            app.EscenarioTab.Title = 'Escenario';
            app.PlotEscenario = uiaxes(app.EscenarioTab);
            title(app.PlotEscenario, 'Escenario')
            xlabel(app.PlotEscenario, 'X')
            ylabel(app.PlotEscenario, 'Y')
            app.CarroSlider_2Label = uilabel(app.EscenarioTab);
            app.CarroSlider_2Label.Text = 'Carro';
            app.CarroSlider_2 = uislider(app.EscenarioTab);
            app.CarroSlider_2.Limits = [-1 1];
            app.CarroSlider_2.MajorTicks = [-1 -0.5 0 0.5 1];
            app.CarroSlider_2.ValueChangingFcn = createCallbackFcn(app, @CarroSliderValueChanging, true);
            app.IzajeSlider_2Label = uilabel(app.EscenarioTab);
            app.IzajeSlider_2Label.Text = 'Izaje';
            app.IzajeSlider_2 = uislider(app.EscenarioTab);
            app.IzajeSlider_2.Limits = [-1 1];
            app.IzajeSlider_2.MajorTicks = [-1 -0.5 0 0.5 1];
            app.IzajeSlider_2.ValueChangingFcn = createCallbackFcn(app, @IzajeSliderValueChanging, true);
            app.PosicionesTab = uitab(app.TabGroup2);
            app.PosicionesTab.Title = 'Posiciones';
            app.PlotPosicionX = uiaxes(app.PosicionesTab);
            title(app.PlotPosicionX, 'Posición X')
            xlabel(app.PlotPosicionX, 't')
            ylabel(app.PlotPosicionX, 'xl(t)')
            app.PlotPosicionY = uiaxes(app.PosicionesTab);
            title(app.PlotPosicionY, 'Posición Y')
            xlabel(app.PlotPosicionY, 't')
            ylabel(app.PlotPosicionY, 'yl(t)')
            app.VelocidadesTab = uitab(app.TabGroup2);
            app.VelocidadesTab.Title = 'Velocidades';
            app.PlotVelocidadX = uiaxes(app.VelocidadesTab);
            title(app.PlotVelocidadX, 'Posición X')
            xlabel(app.PlotVelocidadX, 't')
            ylabel(app.PlotVelocidadX, 'xl(t)')
            app.PlotVelocidadY = uiaxes(app.VelocidadesTab);
            title(app.PlotVelocidadY, 'Posición Y')
            xlabel(app.PlotVelocidadY, 't')
            ylabel(app.PlotVelocidadY, 'yl(t)')
            % Show the figure after all components are created
            app.ControlPuenteGruaUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Automatas

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ControlPuenteGruaUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ControlPuenteGruaUIFigure)
        end
    end
end